classdef Setayesh_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Applicatipn                    matlab.ui.Figure
        LSTMBasedonSkeletonPanel       matlab.ui.container.Panel
        InputParameterforLSTMNetworkLabel  matlab.ui.control.Label
        InputSizeFeaureSizeLabel       matlab.ui.control.Label
        InputSize                      matlab.ui.control.NumericEditField
        MiniBatchSizeLabel             matlab.ui.control.Label
        MinBatch                       matlab.ui.control.NumericEditField
        MaximumEpochesLabel            matlab.ui.control.Label
        MaximumEpoches                 matlab.ui.control.NumericEditField
        NumHiddenUnitLabel             matlab.ui.control.Label
        NumHiddenUnit                  matlab.ui.control.NumericEditField
        RunBasedalgorithmButton        matlab.ui.control.Button
        ShowChartofResultButton        matlab.ui.control.Button
        CM                             matlab.ui.control.Table
        ConfusionMatrixLabel           matlab.ui.control.Label
        InitialLearnRateLabel          matlab.ui.control.Label
        lr                             matlab.ui.control.NumericEditField
        GradientThresholdLabel         matlab.ui.control.Label
        gradiant                       matlab.ui.control.NumericEditField
        ResultofBasedLSTMNetworkonDataLabel  matlab.ui.control.Label
        acc_b                          matlab.ui.control.Label
        AccuracyLabel                  matlab.ui.control.Label
        PrecisionLabel                 matlab.ui.control.Label
        prec_b                         matlab.ui.control.Label
        RecallLabel                    matlab.ui.control.Label
        rec_b                          matlab.ui.control.Label
        ErrorRateLabel                 matlab.ui.control.Label
        error_b                        matlab.ui.control.Label
        ProposedMethodCNNandLSTMPanel  matlab.ui.container.Panel
        InputParameterforDiffrentPartofProposedMethodLabel  matlab.ui.control.Label
        InputSizeFeaureSizeLabel_2     matlab.ui.control.Label
        InputSize_p                    matlab.ui.control.NumericEditField
        MiniBatchSizeLabel_2           matlab.ui.control.Label
        MinBatch_p                     matlab.ui.control.NumericEditField
        MaximumEpochesLabel_2          matlab.ui.control.Label
        MaximumEpoches_p               matlab.ui.control.NumericEditField
        NumHiddenUnitLabel_2           matlab.ui.control.Label
        NumHiddenUnit_p                matlab.ui.control.NumericEditField
        InitialLearnRateLabel_2        matlab.ui.control.Label
        lr_p                           matlab.ui.control.NumericEditField
        GradientThresholdLabel_2       matlab.ui.control.Label
        gradiant_p                     matlab.ui.control.NumericEditField
        RunalgorithmButton             matlab.ui.control.Button
        ShowChartofResultButton_p      matlab.ui.control.Button
        SelectPreTrainedCNNNetworkforFeatureExtractionButtonGroup  matlab.ui.container.ButtonGroup
        Googlenet                      matlab.ui.control.RadioButton
        Alexnet                        matlab.ui.control.RadioButton
        SelectSolverFunctionforTrainLSTMNetworkButtonGroup  matlab.ui.container.ButtonGroup
        rmsprop                        matlab.ui.control.RadioButton
        sgdm                           matlab.ui.control.RadioButton
        TestRationLabel                matlab.ui.control.Label
        test_ratio                     matlab.ui.control.NumericEditField
        Comparison                     matlab.ui.control.Button
        IputforPartionDataLabel        matlab.ui.control.Label
        IputforLSTMNetworkLabel        matlab.ui.control.Label
        IputforBagofVisualWordLabel    matlab.ui.control.Label
        CountReplicationLabel          matlab.ui.control.Label
        replication                    matlab.ui.control.NumericEditField
        MaximumIterrationLabel         matlab.ui.control.Label
        itration                       matlab.ui.control.NumericEditField
        NumberofClusterLabel           matlab.ui.control.Label
        cluster                        matlab.ui.control.NumericEditField
        ConfusionMatrixLabel_2         matlab.ui.control.Label
        ResultofProposedMethodCNNandLSTMNetworkLabel  matlab.ui.control.Label
        acc_p                          matlab.ui.control.Label
        AccuracyLabel_2                matlab.ui.control.Label
        PrecisionLabel_2               matlab.ui.control.Label
        prec_p                         matlab.ui.control.Label
        RecallLabel_2                  matlab.ui.control.Label
        rec_p                          matlab.ui.control.Label
        ErrorRateLabel_2               matlab.ui.control.Label
        error_p                        matlab.ui.control.Label
        CM_p                           matlab.ui.control.Table
    end

    
    properties (Access = public)
        TR_b=table(); % table result base
        TR =table();  % table result propose
    end
    

    methods (Access = private)

        % Button pushed function: RunBasedalgorithmButton
        function RunBasedalgorithmButtonPushed(app, event)
            clear vars;
            % Read Data
            Data=load('Data2.mat');
            Data=Data.Data;
            Label_fal=load('Label.mat');
            Label_fal=categorical(Label_fal.label);
            Label_action=load('Label2.mat');
            Label_action=categorical(Label_action.label2);
            for i=1: length(Data)
                temp=[];
                for j=length(Data{i,1})-28:length(Data{i,1}) %j=1:length(Data{i})
                    temp=[temp,Data{i}(:,:,j)];
                end
                Convert_Data{i,1}=temp;
            end
            % Sort Data Based on Length
            % numObservations = numel(Convert_Data);
            % for i=1:numObservations
            %     sequence = Convert_Data{i};
            %     sequenceLengths(i) = size(sequence,2);
            % end
            % [sequenceLengths,idx] = sort(sequenceLengths);
            % Convert_Data = Convert_Data(idx);
            % Label_fal=Label_fal(idx);
            % Label_action=Label_action(idx);
            p_b=0.155;
            [n_b,~]=size(Convert_Data);
            CV = cvpartition(n_b,'Holdout',p_b);
            testIdx = gather(CV.test);
            trainIdx = gather(CV.training);
            XTrain_b = Convert_Data(trainIdx,:);
            YTrain_b = Label_fal(trainIdx,:);
            XTest_b = Convert_Data(testIdx,:);
            YTest_b =  Label_fal(testIdx,:);
            % Create LSTM
            inputSize_b =app.InputSize.Value;
            numHiddenUnits_b =app.NumHiddenUnit.Value;
            numClasses_b = 2;
            miniBatchSize_b=app.MinBatch.Value;
            maxEpochs_b=app.MaximumEpoches.Value;
            layers_b = [ ...
                sequenceInputLayer(inputSize_b)
                lstmLayer(numHiddenUnits_b,'OutputMode','last')
                fullyConnectedLayer(numClasses_b)
                softmaxLayer
                classificationLayer];
            options_b = trainingOptions('rmsprop', ...
                'ExecutionEnvironment','cpu', ...
                'InitialLearnRate',app.lr.Value, ...
                'MaxEpochs',maxEpochs_b, ...
                'MiniBatchSize',miniBatchSize_b, ...
                'GradientThreshold',app.gradiant.Value, ...
                'Verbose',false, ...
                'Plots','training-progress');
            % Train LSTM Network
            lstmnet = trainNetwork(XTrain_b,YTrain_b,layers_b,options_b);
            % Test LSTM Network
            YPred_b = classify(lstmnet,XTest_b);
            % Result
            accuracy_b=(sum(YPred_b == YTest_b))/numel(YTest_b)*100;
            figure
            plotconfusion(YTest_b,YPred_b,'Performance LSTM Base')
            matrix_result_b=confusionmat(YTest_b,YPred_b);
            set(app.CM, 'Data', matrix_result_b')
            for i =1:size(matrix_result_b,1)
                recall_b(i)=matrix_result_b(i,i)/sum(matrix_result_b(i,:));
            end
            recall_b(isnan(recall_b))=[];
            Recall_b=(sum(recall_b)/size(matrix_result_b,1))*100;
            for i =1:size(matrix_result_b,1)
                precision_b(i)=matrix_result_b(i,i)/sum(matrix_result_b(:,i));
            end
            precision_b(isnan(precision_b))=[];
            Precision_b=(sum(precision_b)/size(matrix_result_b,1))*100;
            % Table Result
            app.TR_b=table();
            app.TR_b.Accuracy=accuracy_b;
            app.TR_b.Precision=Precision_b;
            app.TR_b.Recall=Recall_b;
            app.acc_b.Text=num2str(round(accuracy_b,2));
            app.error_b.Text=num2str(round(100-accuracy_b,2));
            app.prec_b.Text=num2str(round(Precision_b,2));
            app.rec_b.Text=num2str(round(Recall_b,2));
            if((height(app.TR)~=0))
                set(app.Comparison,'Enable','on')
            end
            %writetable(app.TR_b,'ResultBase.xlsx')
            writetable(app.TR_b, fullfile(pwd, 'ResultBase.xlsx'))
            system('taskkill /F /IM EXCEL.EXE');
        end

        % Button pushed function: ShowChartofResultButton
        function ShowChartofResultButtonPushed(app, event)
            figure;
            ax_b = axes; % create axes
            bar(diag(table2array(app.TR_b(:,1:end))),'stacked'); % plot data
            legend({'Accuracy','Precision','Recall'},'location','northwest');
            set(gca,'XTickLabel',(app.TR_b.Properties.VariableNames(1:end)));
            text([1:length(table2array(app.TR_b(:,1:end)))], table2array(app.TR_b(:,1:end))', num2str(table2array(app.TR_b(:,1:end))','%0.2f'),'HorizontalAlignment','center','VerticalAlignment','top')
            title('Result LSTM Base')
            ylim([0,100])
            set(gca,'YTick',[0 : 10 : 100]);
        end

        % Button pushed function: RunalgorithmButton
        function RunalgorithmButtonPushed(app, event)
            clear vars;
            imds = imageDatastore('Data-RGB', ...
                'IncludeSubfolders',true, ...
                'LabelSource','foldernames');
            % Feature Extraction
            if (app.Googlenet.Value==true)
                net=googlenet;
                inputSize = net.Layers(1).InputSize;
                layer ='pool5-drop_7x7_s1'; %'loss3-classifier';
            else
                net = alexnet;
                inputSize = net.Layers(1).InputSize;
                layer ='fc8';
            end
            augimdsTrain = augmentedImageDatastore(inputSize(1:2),imds);
            Allfeatures = activations(net,augimdsTrain,layer,'OutputAs','rows');
            % show status
            h = msgbox('Features Extractd...! Creation Bag of Visual Word Started..!');
            % Bag Of Visual Words
            k=app.cluster.Value;
            histogram_all=[];
            labelVector=[];
            [idx,~,~,~] = kmeans(Allfeatures,k,'MaxIter',app.itration.Value,...
                'Display','final','Replicates',app.replication.Value);
            T = countEachLabel(imds);
            count=1;
            for i=1: height(T)
                histogram=zeros(1,k);
                some_idx=idx(count:count-1+T{i,{'Count'}});
                un =unique(some_idx);
                frquency=histc(some_idx, un);
                histogram(un)=frquency;
                histogram_all=[histogram_all;histogram];
                if (startsWith(char(imds.Labels(count)),'f'))
                    labelVector=[labelVector;categorical({'Fall'})];
                else
                    labelVector=[labelVector;categorical({'ADL'})];
                end
                count=count+T{i,{'Count'}};
            end
            % Partition Data
            histogram_all= num2cell(histogram_all,2);
            p=app.test_ratio.Value;
            [n,~]=size(histogram_all);
            CV0 = cvpartition(n,'Holdout',p);
            testIdx0 = gather(CV0.test);
            trainIdx0 = gather(CV0.training);
            XTrain = histogram_all(trainIdx0,:);
            YTrain = labelVector(trainIdx0);
            XTest = histogram_all(testIdx0,:);
            YTest =  labelVector(testIdx0);
            % Create LSTM
            inputSize =app.InputSize_p.Value;
            numHiddenUnits =app.NumHiddenUnit_p.Value;
            numClasses =2;
            miniBatchSize=app.MinBatch_p.Value;
            maxEpochs=app.MaximumEpoches_p.Value;
            layers = [ ...
                sequenceInputLayer(inputSize)
                lstmLayer(numHiddenUnits,'OutputMode','last')
                fullyConnectedLayer(numClasses)
                softmaxLayer
                classificationLayer];
            if(app.rmsprop.Value==true)
                solver='rmsprop';
            else
                solver='sgdm';
            end
            options = trainingOptions(solver, ...
                'ExecutionEnvironment','cpu', ...
                'InitialLearnRate',app.lr_p.Value, ...
                'MaxEpochs',maxEpochs, ...
                'MiniBatchSize',miniBatchSize, ...
                'GradientThreshold',app.gradiant_p.Value, ...
                'Verbose',false, ...
                'Plots','training-progress');
            % Train LSTM Network
            lstmnet_p = trainNetwork(XTrain,YTrain,layers,options);
            % Test LSTM Network
            YPred = classify(lstmnet_p,XTest);
            % Result
            accuracy=(sum(YPred == YTest))/numel(YTest)*100;
            figure
            plotconfusion(YTest,YPred,'Performance LSTM & CNN')
            matrix_result=confusionmat(YTest,YPred);
            set(app.CM_p, 'Data', matrix_result')
            for i =1:size(matrix_result,1)
                recall(i)=matrix_result(i,i)/sum(matrix_result(i,:));
           end
            recall(isnan(recall))=[];
            Recall=(sum(recall)/size(matrix_result,1))*100;
            for i =1:size(matrix_result,1)
                precision(i)=matrix_result(i,i)/sum(matrix_result(:,i));
            end
            precision(isnan(precision))=[];
            Precision=(sum(precision)/size(matrix_result,1))*100;
            % Table Result
            app.TR=table();
            app.TR.Accuracy=accuracy;
            app.TR.Precision=Precision;
            app.TR.Recall=Recall;
            app.acc_p.Text=num2str(round(accuracy,2));
            app.error_p.Text=num2str(round(100-accuracy,2));
            app.prec_p.Text=num2str(round(Precision,2));
            app.rec_p.Text=num2str(round(Recall,2));
            if((height(app.TR_b)==0))
                set(app.Comparison,'Enable','off')
            end
            % writetable(app.TR,'ResultPropose.xlsx')
            writetable(app.TR, fullfile(pwd, 'ResultPropose.xlsx'))
            system('taskkill /F /IM EXCEL.EXE');
        end

        % Button pushed function: ShowChartofResultButton_p
        function ShowChartofResultButton_pPushed(app, event)
            figure
            ax = axes; % create axes
            bar(diag(table2array(app.TR(:,1:end))),'stacked'); % plot data
            legend({'Accuracy','Precison','Recall'},'location','northwest');
            set(gca,'XTickLabel',(app.TR.Properties.VariableNames(1:end)));
            text([1:length(table2array(app.TR(:,1:end)))], table2array(app.TR(:,1:end))',...
                num2str(table2array(app.TR(:,1:end))','%0.2f'),'HorizontalAlignment','center','VerticalAlignment','top')
            title('Result Propose Method CNN & LSTM');
            ylim([0,100])
            set(gca,'YTick',[0 : 10 : 100]);
        end

        % Button pushed function: Comparison
        function ComparisonPushed(app, event)
            comparison=[app.TR ; app.TR_b];
            ytxt = num2cell(table2array(comparison(:,1:end)));
            ytxt = cellfun(@(x) [sprintf('%0.2f',x), '%'], ytxt,'un',0);
            figure
            colormap(gray);
            hB = bar(table2array(comparison(:,1:end)),'group','EdgeColor','w','BarWidth',1);
            legend({'Accuracy','Precison','Recall'},'location','northwest');
            set(gca,'XTickLabel',{'CNN & LSTM' , 'LSTM Base'});
            hT=[];              % placeholder for text object handles
            for i=1:length(hB)  % iterate over number of bar objects
                hT=[hT,text(hB(i).XData+hB(i).XOffset,hB(i).YData, ytxt(:,i), ...
                    'VerticalAlignment','bottom','horizontalalign','center')];
            end
            title('Comparison of Result');
            ylim([0,100])
            set(gca,'YTick',[0 : 10 : 100]);
            %writetable(comparison,'Result_Comparison.xlsx');
            writetable(comparison, fullfile(pwd, 'Result_Comparison.xlsx'))
            system('taskkill /F /IM EXCEL.EXE');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Applicatipn = uifigure;
            app.Applicatipn.Name = 'Proposed Method: Combination of CNN and LSTM';
            app.LSTMBasedonSkeletonPanel = uipanel(app.Applicatipn);
            app.LSTMBasedonSkeletonPanel.Title = 'LSTM Based on Skeleton';
            app.InputParameterforLSTMNetworkLabel = uilabel(app.LSTMBasedonSkeletonPanel);
            app.InputParameterforLSTMNetworkLabel.Text = 'Input Parameter for LSTM Network : ';
            app.InputSizeFeaureSizeLabel = uilabel(app.LSTMBasedonSkeletonPanel);
            app.InputSizeFeaureSizeLabel.Text = 'Input Size(Feaure Size): ';
            app.InputSize = uieditfield(app.LSTMBasedonSkeletonPanel, 'numeric');
            app.InputSize.Enable = 'off';
            app.InputSize.Value = 25;
            app.MiniBatchSizeLabel = uilabel(app.LSTMBasedonSkeletonPanel);
            app.MiniBatchSizeLabel.Text = 'Mini Batch Size: ';
            app.MinBatch = uieditfield(app.LSTMBasedonSkeletonPanel, 'numeric');
            app.MinBatch.Value = 27;
            app.MaximumEpochesLabel = uilabel(app.LSTMBasedonSkeletonPanel);
            app.MaximumEpochesLabel.Text = 'Maximum Epoches: ';
            app.MaximumEpoches = uieditfield(app.LSTMBasedonSkeletonPanel, 'numeric');
            app.MaximumEpoches.Value = 10;
            app.NumHiddenUnitLabel = uilabel(app.LSTMBasedonSkeletonPanel);
            app.NumHiddenUnitLabel.Text = 'Num Hidden Unit: ';
            app.NumHiddenUnit = uieditfield(app.LSTMBasedonSkeletonPanel, 'numeric');
            app.NumHiddenUnit.Value = 100;
            app.RunBasedalgorithmButton = uibutton(app.LSTMBasedonSkeletonPanel, 'push');
            app.RunBasedalgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @RunBasedalgorithmButtonPushed, true);
            app.RunBasedalgorithmButton.Text = 'Run Based algorithm';
            app.ShowChartofResultButton = uibutton(app.LSTMBasedonSkeletonPanel, 'push');
            app.ShowChartofResultButton.ButtonPushedFcn = createCallbackFcn(app, @ShowChartofResultButtonPushed, true);
            app.ShowChartofResultButton.Text = 'Show Chart of Result';
            app.CM = uitable(app.LSTMBasedonSkeletonPanel);
            app.CM.ColumnName = {'ADL'; 'FALL'};
            app.CM.RowName = {};
            app.ConfusionMatrixLabel = uilabel(app.LSTMBasedonSkeletonPanel);
            app.ConfusionMatrixLabel.Text = 'Confusion Matrix';
            app.InitialLearnRateLabel = uilabel(app.LSTMBasedonSkeletonPanel);
            app.InitialLearnRateLabel.Text = 'InitialLearnRate:';
            app.lr = uieditfield(app.LSTMBasedonSkeletonPanel, 'numeric');
            app.lr.Value = 0.01;
            app.GradientThresholdLabel = uilabel(app.LSTMBasedonSkeletonPanel);
            app.GradientThresholdLabel.Text = 'GradientThreshold:';
            app.gradiant = uieditfield(app.LSTMBasedonSkeletonPanel, 'numeric');
            app.gradiant.Value = 1;
            app.ResultofBasedLSTMNetworkonDataLabel = uilabel(app.LSTMBasedonSkeletonPanel);
            app.ResultofBasedLSTMNetworkonDataLabel.Text = 'Result of Based LSTM Network on Data: ';
            app.acc_b = uilabel(app.LSTMBasedonSkeletonPanel);
            app.acc_b.Text = '00';
            app.AccuracyLabel = uilabel(app.LSTMBasedonSkeletonPanel);
            app.AccuracyLabel.Text = 'Accuracy (%)';
            app.PrecisionLabel = uilabel(app.LSTMBasedonSkeletonPanel);
            app.PrecisionLabel.Text = 'Precision(%)';
            app.prec_b = uilabel(app.LSTMBasedonSkeletonPanel);
            app.prec_b.Text = '00';
            app.RecallLabel = uilabel(app.LSTMBasedonSkeletonPanel);
            app.RecallLabel.Text = 'Recall(%)';
            app.rec_b = uilabel(app.LSTMBasedonSkeletonPanel);
            app.rec_b.Text = '00';
            app.ErrorRateLabel = uilabel(app.LSTMBasedonSkeletonPanel);
            app.ErrorRateLabel.Text = 'Error Rate(%)';
            app.error_b = uilabel(app.LSTMBasedonSkeletonPanel);
            app.error_b.Text = '00';
            app.ProposedMethodCNNandLSTMPanel = uipanel(app.Applicatipn);
            app.ProposedMethodCNNandLSTMPanel.Title = 'Proposed Method :CNN and LSTM';
            app.InputParameterforDiffrentPartofProposedMethodLabel = uilabel(app.ProposedMethodCNNandLSTMPanel);
            app.InputParameterforDiffrentPartofProposedMethodLabel.Text = 'Input Parameter for Diffrent Part of Proposed Method:';
            app.InputSizeFeaureSizeLabel_2 = uilabel(app.ProposedMethodCNNandLSTMPanel);
            app.InputSizeFeaureSizeLabel_2.Text = 'Input Size(Feaure Size): ';
            app.InputSize_p = uieditfield(app.ProposedMethodCNNandLSTMPanel, 'numeric');
            app.InputSize_p.Enable = 'off';
            app.InputSize_p.Value = 1;
            app.MiniBatchSizeLabel_2 = uilabel(app.ProposedMethodCNNandLSTMPanel);
            app.MiniBatchSizeLabel_2.Text = 'Mini Batch Size: ';
            app.MinBatch_p = uieditfield(app.ProposedMethodCNNandLSTMPanel, 'numeric');
            app.MinBatch_p.Value = 10;
            app.MaximumEpochesLabel_2 = uilabel(app.ProposedMethodCNNandLSTMPanel);
            app.MaximumEpochesLabel_2.Text = 'Maximum Epoches: ';
            app.MaximumEpoches_p = uieditfield(app.ProposedMethodCNNandLSTMPanel, 'numeric');
            app.MaximumEpoches_p.Value = 100;
            app.NumHiddenUnitLabel_2 = uilabel(app.ProposedMethodCNNandLSTMPanel);
            app.NumHiddenUnitLabel_2.Text = 'Num Hidden Unit: ';
            app.NumHiddenUnit_p = uieditfield(app.ProposedMethodCNNandLSTMPanel, 'numeric');
            app.NumHiddenUnit_p.Value = 100;
            app.InitialLearnRateLabel_2 = uilabel(app.ProposedMethodCNNandLSTMPanel);
            app.InitialLearnRateLabel_2.Text = 'InitialLearnRate:';
            app.lr_p = uieditfield(app.ProposedMethodCNNandLSTMPanel, 'numeric');
            app.lr_p.Value = 0.01;
            app.GradientThresholdLabel_2 = uilabel(app.ProposedMethodCNNandLSTMPanel);
            app.GradientThresholdLabel_2.Text = 'GradientThreshold:';
            app.gradiant_p = uieditfield(app.ProposedMethodCNNandLSTMPanel, 'numeric');
            app.gradiant_p.Value = 1;
            app.RunalgorithmButton = uibutton(app.ProposedMethodCNNandLSTMPanel, 'push');
            app.RunalgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @RunalgorithmButtonPushed, true);
            app.RunalgorithmButton.Text = 'Run algorithm';
            app.ShowChartofResultButton_p = uibutton(app.ProposedMethodCNNandLSTMPanel, 'push');
            app.ShowChartofResultButton_p.ButtonPushedFcn = createCallbackFcn(app, @ShowChartofResultButton_pPushed, true);
            app.ShowChartofResultButton_p.Text = 'Chart of Result';
            app.SelectPreTrainedCNNNetworkforFeatureExtractionButtonGroup = uibuttongroup(app.ProposedMethodCNNandLSTMPanel);
            app.SelectPreTrainedCNNNetworkforFeatureExtractionButtonGroup.Title = 'Select Pre Trained CNN Network for Feature Extraction';
            app.Googlenet = uiradiobutton(app.SelectPreTrainedCNNNetworkforFeatureExtractionButtonGroup);
            app.Googlenet.Text = 'GoogleNet (DAGNetwork with 144 Layers & 22 Depth)';
            app.Googlenet.Value = true;
            app.Alexnet = uiradiobutton(app.SelectPreTrainedCNNNetworkforFeatureExtractionButtonGroup);
            app.Alexnet.Text = 'AlexNet (SeriesNetwork with 25 Layers & 8 depth)';
            app.SelectSolverFunctionforTrainLSTMNetworkButtonGroup = uibuttongroup(app.ProposedMethodCNNandLSTMPanel);
            app.SelectSolverFunctionforTrainLSTMNetworkButtonGroup.Title = 'Select Solver Function for Train LSTM Network';
            app.rmsprop = uiradiobutton(app.SelectSolverFunctionforTrainLSTMNetworkButtonGroup);
            app.rmsprop.Text = 'RMSPROP( Root mean square propagation)';
            app.rmsprop.Value = true;
            app.sgdm = uiradiobutton(app.SelectSolverFunctionforTrainLSTMNetworkButtonGroup);
            app.sgdm.Text = 'SGDM (Stochastic gradient descent with momentum)';
            app.TestRationLabel = uilabel(app.ProposedMethodCNNandLSTMPanel);
            app.TestRationLabel.Text = 'Test Ration :';
            app.test_ratio = uieditfield(app.ProposedMethodCNNandLSTMPanel, 'numeric');
            app.test_ratio.Value = 0.3;
            app.Comparison = uibutton(app.ProposedMethodCNNandLSTMPanel, 'push');
            app.Comparison.ButtonPushedFcn = createCallbackFcn(app, @ComparisonPushed, true);
            app.Comparison.Text = 'Comparison Chart';
            app.IputforPartionDataLabel = uilabel(app.ProposedMethodCNNandLSTMPanel);
            app.IputforPartionDataLabel.Text = 'Iput for Partion Data:';
            app.IputforLSTMNetworkLabel = uilabel(app.ProposedMethodCNNandLSTMPanel);
            app.IputforLSTMNetworkLabel.Text = 'Iput for LSTM Network:';
            app.IputforBagofVisualWordLabel = uilabel(app.ProposedMethodCNNandLSTMPanel);
            app.IputforBagofVisualWordLabel.Text = 'Iput for Bag of Visual Word:';
            app.CountReplicationLabel = uilabel(app.ProposedMethodCNNandLSTMPanel);
            app.CountReplicationLabel.Text = 'Count Replication: ';
            app.replication = uieditfield(app.ProposedMethodCNNandLSTMPanel, 'numeric');
            app.replication.Value = 1;
            app.MaximumIterrationLabel = uilabel(app.ProposedMethodCNNandLSTMPanel);
            app.MaximumIterrationLabel.Text = 'Maximum Iterration:';
            app.itration = uieditfield(app.ProposedMethodCNNandLSTMPanel, 'numeric');
            app.itration.Value = 100;
            app.NumberofClusterLabel = uilabel(app.ProposedMethodCNNandLSTMPanel);
            app.NumberofClusterLabel.Text = 'Number of Cluster: ';
            app.cluster = uieditfield(app.ProposedMethodCNNandLSTMPanel, 'numeric');
            app.cluster.Value = 10;
            app.ConfusionMatrixLabel_2 = uilabel(app.ProposedMethodCNNandLSTMPanel);
            app.ConfusionMatrixLabel_2.Text = 'Confusion Matrix';
            app.ResultofProposedMethodCNNandLSTMNetworkLabel = uilabel(app.ProposedMethodCNNandLSTMPanel);
            app.ResultofProposedMethodCNNandLSTMNetworkLabel.Text = 'Result of Proposed Method CNN and LSTM Network: ';
            app.acc_p = uilabel(app.ProposedMethodCNNandLSTMPanel);
            app.acc_p.Text = '00';
            app.AccuracyLabel_2 = uilabel(app.ProposedMethodCNNandLSTMPanel);
            app.AccuracyLabel_2.Text = 'Accuracy (%)';
            app.PrecisionLabel_2 = uilabel(app.ProposedMethodCNNandLSTMPanel);
            app.PrecisionLabel_2.Text = 'Precision(%)';
            app.prec_p = uilabel(app.ProposedMethodCNNandLSTMPanel);
            app.prec_p.Text = '00';
            app.RecallLabel_2 = uilabel(app.ProposedMethodCNNandLSTMPanel);
            app.RecallLabel_2.Text = 'Recall(%)';
            app.rec_p = uilabel(app.ProposedMethodCNNandLSTMPanel);
            app.rec_p.Text = '00';
            app.ErrorRateLabel_2 = uilabel(app.ProposedMethodCNNandLSTMPanel);
            app.ErrorRateLabel_2.Text = 'Error Rate(%)';
            app.error_p = uilabel(app.ProposedMethodCNNandLSTMPanel);
            app.error_p.Text = '00';
            app.CM_p = uitable(app.ProposedMethodCNNandLSTMPanel);
            app.CM_p.ColumnName = {'ADL'; 'FALL'};
            app.CM_p.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Setayesh_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Applicatipn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Applicatipn)
        end
    end
end